classdef thrust_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        ThrustSliderLabel            matlab.ui.control.Label
        ThrustSlider                 matlab.ui.control.Slider
        Label                        matlab.ui.control.Label
        ScaleReadingkgLabel          matlab.ui.control.Label
        ScaleIPAdressEditFieldLabel  matlab.ui.control.Label
        ScaleIPAdressEditField       matlab.ui.control.EditField
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
       u = udpport("IPV4", 'LocalHost', "0.0.0.0", 'LocalPort', 8000);
       myTimer;
       counter;
    end
    
    methods (Access = private)
        
        function Update(app,~,~)
            data = read(app.u,app.u.NumBytesAvailable,"string"); 
            flush(app.u,"input");
            app.Label.Text = data;
            app.counter = app.counter + 0.5;
            plot(app.UIAxes, app.counter, str2double(data), "b.", "LineWidth",100);
            hold(app.UIAxes, "on");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.counter = 0;
            app.myTimer = timer();
            app.myTimer.Period = 0.5;
            app.myTimer.ExecutionMode = 'fixedRate';
            app.myTimer.TimerFcn = @app.Update; 
            start(app.myTimer);
            
        end

        % Value changed function: ThrustSlider
        function ThrustSliderValueChanged(app, event)
            thrust = app.ThrustSlider.Value;
            ip = app.ScaleIPAdressEditField.Value;
            write(app.u,string(thrust),ip,8888)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.myTimer)
            delete(app.myTimer)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.ThrustSliderLabel = uilabel(app.UIFigure);
            app.ThrustSliderLabel.Text = 'Thrust';
            app.ThrustSlider = uislider(app.UIFigure);
            app.ThrustSlider.ValueChangedFcn = createCallbackFcn(app, @ThrustSliderValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.ScaleReadingkgLabel = uilabel(app.UIFigure);
            app.ScaleReadingkgLabel.Text = 'Scale Reading (kg):';
            app.ScaleIPAdressEditFieldLabel = uilabel(app.UIFigure);
            app.ScaleIPAdressEditFieldLabel.Text = 'Scale IP Adress:';
            app.ScaleIPAdressEditField = uieditfield(app.UIFigure, 'text');
            app.ScaleIPAdressEditField.Value = '192.168.43.110';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Thrust (g)')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = thrust_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end